classdef CES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        elevacion                matlab.ui.control.NumericEditField
        ElevacinLabel            matlab.ui.control.Label
        Azimut                   matlab.ui.control.NumericEditField
        AzimutLabel              matlab.ui.control.Label
        FCENTRAL                 matlab.ui.control.NumericEditField
        FreqCentralMHzLabel      matlab.ui.control.Label
        RX                       matlab.ui.control.NumericEditField
        RXMHzLabel               matlab.ui.control.Label
        AB                       matlab.ui.control.NumericEditField
        ABLabel                  matlab.ui.control.Label
        CalcularPOAN             matlab.ui.control.Button
        orientacion              matlab.ui.control.DropDown
        ORIENTACINDropDownLabel  matlab.ui.control.Label
        longs                    matlab.ui.control.NumericEditField
        LONGITUDLabel_2          matlab.ui.control.Label
        SatliteLabel             matlab.ui.control.Label
        longet                   matlab.ui.control.NumericEditField
        LONGITUDLabel            matlab.ui.control.Label
        latet                    matlab.ui.control.NumericEditField
        LATITUDLabel             matlab.ui.control.Label
        EstacinTerrenaLabel      matlab.ui.control.Label
        CalcularFC               matlab.ui.control.Button
        TX                       matlab.ui.control.NumericEditField
        TXGHzLabel               matlab.ui.control.Label
        CalcularAB               matlab.ui.control.Button
        FM                       matlab.ui.control.NumericEditField
        FMEditFieldLabel         matlab.ui.control.Label
        VINFO                    matlab.ui.control.NumericEditField
        VINFOEditFieldLabel      matlab.ui.control.Label
        ROLLOFF                  matlab.ui.control.NumericEditField
        ROLLOFFEditFieldLabel    matlab.ui.control.Label
        FEC                      matlab.ui.control.NumericEditField
        FECEditFieldLabel        matlab.ui.control.Label
        POSICINDEANTENALabel     matlab.ui.control.Label
        FRECUENCIACENTRALLabel   matlab.ui.control.Label
        ANCHODEBANDALabel        matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularAB
        function CalcularABButtonPushed(app, event)
v=app.VINFO.Value;
roll=app.ROLLOFF.Value;
fec=app.FEC.Value;
fm=app.FM.Value;

ab=v*(1 + roll)*(1 / fec)*(fm);

ab_rounded = round(ab, 2);
   
app.AB.Value = ab_rounded;

        end

        % Button pushed function: CalcularFC
        function CalcularFCButtonPushed(app, event)
tx=app.TX.Value;

if tx >=3 && tx <=7 
    tx_mhz = tx * 1000;
    
    Rx = tx_mhz - 2225;
    
    fce = Rx - 5150;
    fcentral = (fce)*(-1);
     
    app.RX.Value = Rx ;
    app.FCENTRAL.Value = fcentral;
    
elseif tx >=11 && tx <=15
    tx_mhz = tx * 1000;
    
    Rx = tx_mhz - 2300;
    
    fce = Rx - 10750;
    fcentral = (fce)*(-1);
    
    app.RX.Value = Rx ;
    app.FCENTRAL.Value = fcentral;
    
end
 
        end

        % Button pushed function: CalcularPOAN
        function CalcularPOANButtonPushed(app, event)
latET = app.latet.Value; 
LongET = app.longet.Value; 
longS = app.longs.Value;  

ap = atand(tand(longS - LongET)/sind(latET));

wo = cosd(latET) * cosd(longS - LongET);

switch app.orientacion.Value
    case 'Noreste'
        a = 180 + ap;
        
        el = atand((42164.2e+03 - 6378.155e+03 * wo)/(6378.155e+03 * sind(acosd(wo)))) - acosd(wo);
       
        app.Azimut.Value = a;         
        app.elevacion.Value = el;
        
    case 'Noroeste'
        a = 180 - ap;
        
        el = atand((42164.2e+03 - 6378.155e+03 * wo)/(6378.155e+03 * sind(acosd(wo)))) - acosd(wo);
       
        app.Azimut.Value = a;         
        app.elevacion.Value = el;
        
    case 'Sureste'
        
        a = 360 - ap;
        
        el = atand((42164.2e+03 - 6378.155e+03 * wo)/(6378.155e+03 * sind(acosd(wo)))) - acosd(wo);
       
        app.Azimut.Value = a;         
        app.elevacion.Value = el;
        
    case 'Suroeste'
        
        a = ap;
        
        el = atand((42164.2e+03 - 6378.155e+03 * wo)/(6378.155e+03 * sind(acosd(wo)))) - acosd(wo);
       
        app.Azimut.Value = a;         
        app.elevacion.Value = el;
        
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'satelite.jpg';
            app.ANCHODEBANDALabel = uilabel(app.UIFigure);
            app.ANCHODEBANDALabel.Interpreter = 'latex';
            app.ANCHODEBANDALabel.Text = 'ANCHO DE BANDA';
            app.FRECUENCIACENTRALLabel = uilabel(app.UIFigure);
            app.FRECUENCIACENTRALLabel.Interpreter = 'latex';
            app.FRECUENCIACENTRALLabel.Text = 'FRECUENCIA  CENTRAL';
            app.POSICINDEANTENALabel = uilabel(app.UIFigure);
            app.POSICINDEANTENALabel.Interpreter = 'latex';
            app.POSICINDEANTENALabel.Text = 'POSICIÓN DE ANTENA ';
            app.FECEditFieldLabel = uilabel(app.UIFigure);
            app.FECEditFieldLabel.Text = {'FEC'; ''};
            app.FEC = uieditfield(app.UIFigure, 'numeric');
            app.ROLLOFFEditFieldLabel = uilabel(app.UIFigure);
            app.ROLLOFFEditFieldLabel.Text = {'ROLL-OFF'; ''};
            app.ROLLOFF = uieditfield(app.UIFigure, 'numeric');
            app.VINFOEditFieldLabel = uilabel(app.UIFigure);
            app.VINFOEditFieldLabel.Text = 'V-INFO';
            app.VINFO = uieditfield(app.UIFigure, 'numeric');
            app.FMEditFieldLabel = uilabel(app.UIFigure);
            app.FMEditFieldLabel.Text = 'FM';
            app.FM = uieditfield(app.UIFigure, 'numeric');
            app.CalcularAB = uibutton(app.UIFigure, 'push');
            app.CalcularAB.ButtonPushedFcn = createCallbackFcn(app, @CalcularABButtonPushed, true);
            app.CalcularAB.Text = 'Calcular';
            app.TXGHzLabel = uilabel(app.UIFigure);
            app.TXGHzLabel.Text = 'TX(GHz)';
            app.TX = uieditfield(app.UIFigure, 'numeric');
            app.CalcularFC = uibutton(app.UIFigure, 'push');
            app.CalcularFC.ButtonPushedFcn = createCallbackFcn(app, @CalcularFCButtonPushed, true);
            app.CalcularFC.Text = 'Calcular';
            app.EstacinTerrenaLabel = uilabel(app.UIFigure);
            app.EstacinTerrenaLabel.Interpreter = 'latex';
            app.EstacinTerrenaLabel.Text = 'Estación Terrena';
            app.LATITUDLabel = uilabel(app.UIFigure);
            app.LATITUDLabel.Text = 'LATITUD';
            app.latet = uieditfield(app.UIFigure, 'numeric');
            app.LONGITUDLabel = uilabel(app.UIFigure);
            app.LONGITUDLabel.Text = 'LONGITUD';
            app.longet = uieditfield(app.UIFigure, 'numeric');
            app.SatliteLabel = uilabel(app.UIFigure);
            app.SatliteLabel.Interpreter = 'latex';
            app.SatliteLabel.Text = 'Satélite';
            app.LONGITUDLabel_2 = uilabel(app.UIFigure);
            app.LONGITUDLabel_2.Text = 'LONGITUD';
            app.longs = uieditfield(app.UIFigure, 'numeric');
            app.ORIENTACINDropDownLabel = uilabel(app.UIFigure);
            app.ORIENTACINDropDownLabel.Text = 'ORIENTACIÓN';
            app.orientacion = uidropdown(app.UIFigure);
            app.orientacion.Items = {'Noreste', 'Noroeste', 'Sureste', 'Suroeste'};
            app.orientacion.Value = 'Noreste';
            app.CalcularPOAN = uibutton(app.UIFigure, 'push');
            app.CalcularPOAN.ButtonPushedFcn = createCallbackFcn(app, @CalcularPOANButtonPushed, true);
            app.CalcularPOAN.Text = 'Calcular';
            app.ABLabel = uilabel(app.UIFigure);
            app.ABLabel.Text = 'AB = ';
            app.AB = uieditfield(app.UIFigure, 'numeric');
            app.RXMHzLabel = uilabel(app.UIFigure);
            app.RXMHzLabel.Text = 'RX(MHz) = ';
            app.RX = uieditfield(app.UIFigure, 'numeric');
            app.FreqCentralMHzLabel = uilabel(app.UIFigure);
            app.FreqCentralMHzLabel.Text = 'Freq. Central(MHz) = ';
            app.FCENTRAL = uieditfield(app.UIFigure, 'numeric');
            app.AzimutLabel = uilabel(app.UIFigure);
            app.AzimutLabel.Text = 'Azimut = ';
            app.Azimut = uieditfield(app.UIFigure, 'numeric');
            app.ElevacinLabel = uilabel(app.UIFigure);
            app.ElevacinLabel.Text = 'Elevación = ';
            app.elevacion = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end